classdef CSVtoTimePts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventFinderUIFigure            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        EventFinderTab                 matlab.ui.container.Tab
        SelectFilesButton              matlab.ui.control.Button
        FindtimepointsButton           matlab.ui.control.Button
        readyLampLabel                 matlab.ui.control.Label
        readyLamp                      matlab.ui.control.Lamp
        MarkerFileNameEditFieldLabel   matlab.ui.control.Label
        MarkerFileNameEditField        matlab.ui.control.EditField
        SystemStartTimeEditFieldLabel  matlab.ui.control.Label
        SystemStartTimeEditField       matlab.ui.control.NumericEditField
        NumberofMarkersEditFieldLabel  matlab.ui.control.Label
        NumberofMarkersEditField       matlab.ui.control.NumericEditField
        SelectGroupDropDownLabel       matlab.ui.control.Label
        SelectGroupDropDown            matlab.ui.control.DropDown
        ParticipantCheckBox            matlab.ui.control.CheckBox
        CaregiverCheckBox              matlab.ui.control.CheckBox
        PlotGraphforLabel              matlab.ui.control.Label
        ShowMarkersforLabel            matlab.ui.control.Label
        EDACheckBox                    matlab.ui.control.CheckBox
        HRCheckBox                     matlab.ui.control.CheckBox
        TempCheckBox                   matlab.ui.control.CheckBox
        LoadDataLabel                  matlab.ui.control.Label
        SelectDirectoryButton          matlab.ui.control.Button
        SavingDirectoryLabel           matlab.ui.control.Label
        GroupThresholdTab              matlab.ui.container.Tab
        SelectGroupDropDown_2Label     matlab.ui.control.Label
        SelectGroupDropDownThreshold   matlab.ui.control.DropDown
        UpdateThresholdButton          matlab.ui.control.Button
        HeartRatebpmEditFieldLabel     matlab.ui.control.Label
        HeartRatebpmEditField          matlab.ui.control.NumericEditField
        ElectrodermalActivitychangeusEditFieldLabel  matlab.ui.control.Label
        ElectrodermalActivitychangeusEditField  matlab.ui.control.NumericEditField
        TemperaturechangedegreeEditFieldLabel  matlab.ui.control.Label
        TemperaturechangedegreeEditField  matlab.ui.control.NumericEditField
        SelectUserDropDownLabel        matlab.ui.control.Label
        SelectUserDropDown             matlab.ui.control.DropDown
        HRSFEditFieldLabel             matlab.ui.control.Label
        HRSFEditField                  matlab.ui.control.NumericEditField
        EDASFEditFieldLabel            matlab.ui.control.Label
        EDASFEditField                 matlab.ui.control.NumericEditField
        TEMPSFEditFieldLabel           matlab.ui.control.Label
        TEMPSFEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        % Table version of the CSV table
        % Participant
        BVP_p = table();
        HR_p = table();
        TEMP_p = table();
        EDA_p = table();
        % Care-giver
        BVP_c = table();
        HR_c = table();
        TEMP_c = table();
        EDA_c = table();
        
        % Signal quality index
        % Participant
        SQI_TEMP_p = [];
        SQI_EDA_p = [];
        
        % Care-giver
        SQI_TEMP_c = [];
        SQI_EDA_c = [];
        
        % Misc variables
        number_markers = 1;
        group = '';
        thresholds = struct();
        start_time = 0;
        is_ready = 0;
        
        % Algorithm related variables
        event_type_p = [];
        event_type_c = [];
       
        % default saving directory:
        saving_directory = pwd;
    end
    
    % Helper functions
    methods (Access = private)
        % Get the last time index the data table for participant and care-giver
        function end_time = get_end_time(app,type)
            if(strcmp(type,"p"))
                hr_start = str2num(app.HR_p{end,1}{1});
                temp_start = str2num(app.TEMP_p{end,1}{1});
                eda_start = str2num(app.EDA_p{end,1}{1});
                end_time = max([hr_start,temp_start,eda_start]);
            else
                hr_start = str2num(app.HR_c{end,1}{1});
                temp_start = str2num(app.TEMP_c{end,1}{1});
                eda_start = str2num(app.EDA_c{end,1}{1});
                end_time = max([hr_start,temp_start,eda_start]);
            end
        end
        
        % Average the raw data and return an averaged array along with the corresponding time array.
        % Also, tag which time is a bad time that should be ignored if there happen to have event.
        % To get the bad time this function use the signal quality index calculated elsewhere in the application
        function [averaged_array,time_array,bad_time_array,averaged_sqi] = get_average(app,type_part,type,win_size,start,stop)
            
            % Setting default values for some variables
            data = [];
            time = [];
            sqi = [];
            sqi_threshold = 0;
            current_index = 1;
            arr_ind = 1;
            bad_arr_ind = 1;
            bad_time_array = [];
            averaged_sqi = [];
            
            % First populate variables with the right type
            if(strcmp(type_part,"p"))
                hr = app.HR_p;
                temp = app.TEMP_p;
                eda = app.EDA_p;
                sqi_temp = app.SQI_TEMP_p;
                sqi_eda = app.SQI_EDA_p;
            else
                hr = app.HR_c;
                temp = app.TEMP_c;
                eda = app.EDA_c;
                sqi_temp = app.SQI_TEMP_c;
                sqi_eda = app.SQI_EDA_c;
            end
            
            % Select the right time, data and sqi depending on the data type
            if(strcmp(type,'hr'))
                time = hr{1:end,1};
                data = hr{1:end,2};
            elseif(strcmp(type,'temp'))
                time = temp{1:end,1};
                data = temp{1:end,2};
                sqi = sqi_temp;
                sqi_threshold = mean(sqi)-std(sqi);
                display(['Threshold Temperature: ', num2str(sqi_threshold)]);
            elseif(strcmp(type,'eda'))
                time = eda{1:end,1};
                data = eda{1:end,2};
                sqi = sqi_eda;
                sqi_threshold = mean(sqi)-std(sqi); 
                display(['Threshold EDA: ', num2str(sqi_threshold)]);
            end
            
            
            max_index = size(time,1);
            
            % TODO comment this out and check if this part is allright
            % Averaging step:
            % Going from start time to stop time with a given window size
            % we averaged the data points that are within.
            for threshold = start:win_size:stop-win_size
                %Reset some variables and get current time
                total_size = 0;
                total_data = 0;
                total_sqi = [];
                isBadTime = 0;
                current_time = str2num(time{current_index,1});
                % While we are in that window we sum the data points together
                % if the sqi is too low for one point in this window we flag the whole thing as bad
                while(current_time < (threshold+win_size) && (current_index < max_index))
                    total_data = total_data + str2num(data{current_index,1});
                    total_size = total_size + 1;
                    if(~strcmp(type,'hr'))
                        total_sqi = [total_sqi, sqi(current_index)];    
                    end
                    
                    if(~strcmp(type,'hr') && sqi(current_index) < sqi_threshold)
                        isBadTime = 1;
                    end
                    current_index = current_index + 1;
                    current_time = str2num(time{current_index,1});
                end
                
                % If we averaged something within this window we add to the averaged
                % array and we choose the middle point to be the time stamp
                % if this was a bad sqi window we record that time stamp as being unreliable.
                if(total_size > 0)
                    averaged_data = total_data/total_size;
                    averaged_array(arr_ind,1) = averaged_data;
                    time_array(arr_ind,1) = threshold+(win_size/2);
                    if(~strcmp(type,'hr'))
                        averaged_sqi(arr_ind) = min(total_sqi);
                    end
                    if(isBadTime)
                        bad_time_array(bad_arr_ind,1) = time_array(arr_ind,1);
                        bad_arr_ind = bad_arr_ind + 1;
                    end
                    arr_ind = arr_ind + 1;
                end
            end
            
        end
        
        % Find the events for a participant or a caregiver and return them
        function [return_events,hr_time,hr_averaged,temp_time,temp_averaged,temp_averaged_sqi,eda_time,eda_averaged,eda_averaged_sqi] = find_events(app,type)
            
            % Setting variables
            r_start_time = app.start_time;
            end_time = get_end_time(app,type);
            win_size = 500;
            events = struct();
            events.eda.time = [];
            events.eda.diff = [];
            events.hr.time = [];
            events.hr.diff = [];
            events.temp.time = [];
            events.temp.diff = [];
            
            % Get the averaged values for each data type
            [hr_averaged,hr_time,bad_hr_time,~] = get_average(app,type,'hr',win_size,r_start_time,end_time);
            [temp_averaged,temp_time,bad_temp_time,temp_averaged_sqi] = get_average(app,type,'temp',win_size,r_start_time,end_time);
            [eda_averaged,eda_time,bad_eda_time,eda_averaged_sqi] = get_average(app,type,'eda',win_size,r_start_time,end_time);
            
            %% Additional signal processing
            % Apply Moving Average Filter on EDA and Temperature
                eda_averaged = movingaverage_2(eda_averaged,15)';
                temp_averaged = movingaverage_2(temp_averaged,35)';
            %Apply Moving Average Filter on HR
                hr_averaged = movingaverage_2(hr_averaged,55)';
                hrDelay = (55-1)/2;
                hr_time = hr_time-hrDelay;
                
            % Concatenate the bad times for TEMP and EDA and take only the non redondant ones
            bad_time = unique([bad_temp_time;bad_eda_time]);

            % get the right threshold depending on participant or a care-giver
            if(strcmp(type,'p'))
                curr_thresh = app.thresholds.p;
            elseif(strcmp(type,'c'))
                curr_thresh = app.thresholds.c;
            end
            
         if(app.HRCheckBox.Value == 1)
            %% HR event detection
            % An event is tagged if there is a prominent peak (default 10 bpm)
            [hr_peak_val,hr_peak_idx,~,hr_peak_prom] = findpeaks(hr_averaged,'MinPeakProminence',curr_thresh.hr); 
            hr_peak_time = hr_time(hr_peak_idx);
            %Find Valleys
            hr_inverted = -hr_averaged;
            [hr_valley_val,hr_valley_idx,~,hr_valley_prom] = findpeaks(hr_inverted,'MinPeakProminence',curr_thresh.hr);
            hr_valley_time = hr_time(hr_valley_idx);
            %Concatenate Times and Prominences
            events.hr.alltime = vertcat(hr_peak_time,hr_valley_time);
            hr_allprom = vertcat(hr_peak_prom,hr_valley_prom);
            %Find times that are not bad times
            counter=1;
            for i = 1:(size(events.hr.alltime))
                if (~ismember(events.hr.alltime(i,1),bad_time))
                    events.hr.time(counter,1)=events.hr.alltime(i,1);
                    counter=counter+1;
                end
            end
            %Find prominences associated to good marker times
            [~,hr_log] = ismember(events.hr.alltime,events.hr.time);
            hr_prom = hr_allprom(find(hr_log));
            %Find differences
            hr_change = hr_prom-curr_thresh.hr;
            events.hr.diff = [events.hr.diff; hr_change];
            %Convert time to seconds
            events.hr.time = (events.hr.time-r_start_time)/1000;
         end
            
         if(app.EDACheckBox.Value == 1)
            %% EDA event detection
            % An event is tagged if there is a given change in a 10 second interval (default about 0.1 microsemens)
            offset = 10/0.5;
            for i = 1:(size(eda_time,1)-offset)
                eda_change = abs(eda_averaged(i+offset,1) - eda_averaged(i,1)) ;
                if(eda_change > curr_thresh.eda && ~ismember(eda_time(i,1),bad_time))
                    events.eda.time = [events.eda.time; (eda_time(floor(i+offset/2),1)-r_start_time)/1000];
                    events.eda.diff = [events.eda.diff; eda_change/curr_thresh.eda];
                end
            end
         end
            
         if(app.TempCheckBox.Value == 1)
            %% TEMP event detection
            % An event is tagged if there is a prominent peak (default 0.1 degree)
            [temp_peak_val,temp_peak_idx,~,temp_peak_prom] = findpeaks(temp_averaged,'MinPeakProminence',curr_thresh.temp); 
            temp_peak_time = temp_time(temp_peak_idx);
            %Find Valleys
            temp_inverted = -temp_averaged;
            [temp_valley_val,temp_valley_idx,~,temp_valley_prom] = findpeaks(temp_inverted,'MinPeakProminence',curr_thresh.temp);
            temp_valley_time = temp_time(temp_valley_idx);
            %Concatenate Times and Prominences
            events.temp.alltime = vertcat(temp_peak_time,temp_valley_time);
            temp_allprom = vertcat(temp_peak_prom,temp_valley_prom);
            %Find times that are not bad times
            counter=1;
            for i = 1:(size(events.temp.alltime))
                if (~ismember(events.temp.alltime(i,1),bad_time))
                    events.temp.time(counter,1)=events.temp.alltime(i,1);
                    counter=counter+1;
                end
            end
            %Find prominences associated to good marker times
            [~,temp_log] = ismember(events.temp.alltime,events.temp.time);
            temp_prom = temp_allprom(find(temp_log));
            %Find differences
            temp_change = temp_prom-curr_thresh.temp;
            events.temp.diff = [events.temp.diff; temp_change];
            %Convert time to seconds
            events.temp.time = (events.temp.time-r_start_time)/1000;
         end
            
            %% Logging all the event into a log file
            eda_events_num = size(events.eda.time,1);
            hr_events_num = size(events.hr.time,1);
            temp_events_num = size(events.temp.time,1);
            filename = strcat(app.MarkerFileNameEditField.Value,'_',type,'.log');
            filename = strcat(app.saving_directory,'/',filename);     
            fileID = fopen(filename,'w');
            fprintf(fileID,'Date : %s\n',date);
            fprintf(fileID,'Type : %s\n',type);
            fprintf(fileID,'EDA EVENTS: %d | HR EVENTS: %d | TEMP EVENTS: %d\n',eda_events_num,hr_events_num,temp_events_num);
            fprintf(fileID,'mod,time,diff\n');
            for i = 1:size(events.eda.time,1)
                mod = 1;
                fprintf(fileID,'%d, %d ,%d \n',mod,int32(events.eda.time(i,1)),events.eda.diff(i,1));
            end
            for i = 1:size(events.hr.time,1)
                mod = 2;
                fprintf(fileID,'%d, %d, %d \n',mod,int32(events.hr.time(i,1)),events.hr.diff(i,1));
            end
            for i = 1:size(events.temp.time,1)
                mod = 3;
                fprintf(fileID,'%d, %d, %d \n',mod,int32(events.temp.time(i,1)),events.temp.diff(i,1));
            end
            fclose(fileID);
            
            
            %% Most meaningful events selection
            % Here we select the events with the greatest difference to their respective threshold
            % we select the top #Number of Markers.
            
            % Variables instantiation
            return_events = [];
            event_type= [];
            i = 1;
            while(i <= app.number_markers)
               
                % Get the maximum for each one of them
                [max_eda,ind_eda] = max(events.eda.diff);
                [max_hr,ind_hr] = max(events.hr.diff);
                [max_temp,ind_temp] = max(events.temp.diff);
                
                %Adjust weight of data type with scaling factor
                max_eda= max_eda*curr_thresh.eda_sf;
                max_hr= max_hr*curr_thresh.hr_sf;
                max_temp= max_temp*curr_thresh.temp_sf;
                
                % If one of the data type was empty replace with a -1
                if(isempty(max_eda))
                    max_eda = -1;
                end
                if(isempty(max_hr))
                    max_hr = -1;
                end
                if(isempty(max_temp))
                    max_temp = -1;
                end
                
                display(max_hr)
                display(max_eda)
                display(max_temp)
                % If all of them are empty we just break out
                if(max_temp <= -1 && max_hr <= -1 && max_eda <= -1)
                    display('No more significant events')
                    break;
                    
                end
                
                % Here we check which one has the greatest difference and select
                % its time as well as its data type as marker. If there is already an event
                % for that time period we simply ignore it.
                isAdded = 0;
                
                % Window size to allow for event
                EDA_win = 5;
                TEMP_win = 10;
                HR_win = 5;
                if((max_eda >= max_hr) && (max_eda >= max_temp) && max_eda ~= -1)
                    curr_eda_time = events.eda.time(ind_eda,1)
                    lower_bound = curr_eda_time - EDA_win;
                    upper_bound = curr_eda_time + EDA_win;
                    if(~isempty(return_events(return_events > lower_bound & return_events < upper_bound)))
                        isAdded = 0;
                    else
                        return_events = [return_events ; int32(events.eda.time(ind_eda,1))];
                        event_type = [event_type; 1];
                        display(['EDA : ', num2str(events.eda.diff(ind_eda,1))]);
                        isAdded = 1;
                    end
                    events.eda.diff(ind_eda,1) = -1;
                elseif((max_hr >= max_eda) && (max_hr >= max_temp) && max_hr ~= -1)
                    curr_hr_time = events.hr.time(ind_hr,1)
                    lower_bound = curr_hr_time - HR_win;
                    upper_bound = curr_hr_time + HR_win;                    
                    if(~isempty(return_events(return_events > lower_bound & return_events < upper_bound)))
                        isAdded = 0;
                    else
                        return_events = [return_events ; int32(events.hr.time(ind_hr,1))];
                        display(['HR : ', num2str(events.hr.diff(ind_hr,1))]);
                        event_type = [event_type; 2];
                        isAdded = 1;
                    end
                    events.hr.diff(ind_hr,1) = -1;
                elseif((max_temp >= max_eda) && (max_temp >= max_hr) && max_temp ~= -1)
                    curr_temp_time = events.temp.time(ind_temp,1)
                    lower_bound = curr_temp_time - TEMP_win;
                    upper_bound = curr_temp_time + TEMP_win;                    
                    if(~isempty(return_events(return_events > lower_bound & return_events < upper_bound)))
                        isAdded = 0;
                    else
                        return_events = [return_events ; int32(events.temp.time(ind_temp,1))];
                        display(['TEMP : ', num2str(events.temp.diff(ind_temp,1))]);
                        event_type = [event_type; 3];
                        isAdded = 1;
                    end
                    events.temp.diff(ind_temp,1) = -1;
                end
                
                if(isAdded == 1)
                    i = i + 1;
                end   
            end
            
            % Here we sort the data in increasing order of time
            % We save the types and we return the events
            sort_matrix = [return_events,event_type];
            sorted_type = sortrows(sort_matrix,1);
            if(strcmp(type,'p'))
                app.event_type_p = sorted_type(:,2);
                assignin('base','type_p',sorted_type(:,2));
            elseif(strcmp(type,'c'))
                app.event_type_c = sorted_type(:,2);
                assignin('base','type_c',sorted_type(:,2));
            end
            return_events = sort(return_events);
        end
        
        % Save the events into the master marker file for the electron app
        function save_events(app,events,type)
            % Setting up the file
            file_name = app.MarkerFileNameEditField.Value;
            file_name = strcat(file_name,".json");
            file_name = strcat(app.saving_directory,'/',file_name);
            fileID = fopen(file_name,'a');
            
            % Setting up participant/care-giver specific variables
            if(strcmp(type,"p"))
                class = "marker-red";
                fprintf(fileID,'[\n');
                event_type = app.event_type_p;
            else
                class = "marker-blue";
                event_type = app.event_type_c;
            end
            
            % Iterate through the events and write them into the JSON file
            for i = 1:size(events,1)
                if(event_type(i) == 1)
                    mod_type = 1;
                elseif(event_type(i) == 2)
                    mod_type = 2;
                else
                    mod_type = 3;
                end
                if(i ~= size(events,1) || strcmp(type,"p"))
                    fprintf(fileID,'{"time": %d, "text": "%d", "class": "%s"},\n',events(i),mod_type,class);
                else
                    fprintf(fileID,'{"time": %d, "text": "%d", "class": "%s"}\n',events(i),mod_type,class);
                end
            end
            
            if(strcmp(type,"c"))
                fprintf(fileID,']\n');
            end
            fclose(fileID);
        end
        
        % Works the same as save_events except here we save the data from
        % the participant and the care-giver into two different files
        
        %TODO merge this function with the save_events functions with a switch
        %variable to control its behavior.
        function save_events_type(app,events,type)
            file_name = app.MarkerFileNameEditField.Value;
            file_name = strcat(file_name,'_',type);
            file_name = strcat(file_name,".json");
            file_name = strcat(app.saving_directory,'/',file_name);
            fileID = fopen(file_name,'w');
            if(strcmp(type,"p"))
                class = "marker-red";
                event_type = app.event_type_p;
            else
                class = "marker-blue";
                event_type = app.event_type_c;
            end
            fprintf(fileID,'[\n');
            for i = 1:size(events,1)
                if(event_type(i) == 1)
                    type = 1;
                elseif(event_type(i) == 2)
                    type = 2;
                else
                    type = 3;
                end
                if(i ~= size(events,1) || strcmp(type,"p"))
                    fprintf(fileID,'{"time": %d, "text": "%d", "class": "%s"},\n',events(i),type,class);
                else
                    fprintf(fileID,'{"time": %d, "text": "%d", "class": "%s"}\n',events(i),type,class);
                end
            end
            fprintf(fileID,']\n');
            fclose(fileID);
        end
        
    
        % This function calls the sqi code that Jason developped and return the sqi for the three variables
        function [bvp_filt,bvp_score,bvp_sqi,sc_filt,sc_score,sc_sqi,temp_filt,temp_score,temp_sqi] = get_sqi(app,type_part)
            if(strcmp(type_part,"p"))
                bvp = app.BVP_p;
                temp = app.TEMP_p;
                eda = app.EDA_p;
            else
                bvp = app.BVP_c;
                temp = app.TEMP_c;
                eda = app.EDA_c;
            end
            
            bvp_mat = str2double(bvp{1:end,2});
            temp_mat = str2double(temp{1:end,2});
            eda_mat = str2double(eda{1:end,2});
            
            [bvp_filt,bvp_score,bvp_sqi,sc_filt,sc_score,sc_sqi,temp_filt,temp_score,temp_sqi] = comp_SQI_3(bvp_mat,eda_mat,temp_mat);
        end
        
        % Saving the averaged SQI values into a Log file
        function save_sqi(app,type,bvp_score,eda_score,temp_score)
            file_name = app.MarkerFileNameEditField.Value;
            file_name = strcat(file_name,'.sqi');
            file_name = strcat(app.saving_directory,'/',file_name);
            fileID = fopen(file_name,'a');
            fprintf(fileID,'type: %s \nbvp score: %d , eda score: %d and temp score: %d\n',type,bvp_score,eda_score,temp_score);
            fclose(fileID);
        end


        function populate_group_threshold(app,selected_group,selected_user)
            % Loading the Group structure
            Group = load('Group.mat');
            Group = Group.Group;
            
            % Changing the right field in the Group structure
            if(strcmp(selected_group,'Blue'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatebpmEditField.Value = Group.blue.p.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.blue.p.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.blue.p.temp;
                    app.HRSFEditField.Value = Group.blue.p.hr_sf;
                    app.EDASFEditField.Value = Group.blue.p.eda_sf;
                    app.TEMPSFEditField.Value = Group.blue.p.temp_sf;
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatebpmEditField.Value = Group.blue.c.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.blue.c.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.blue.c.temp;
                    app.HRSFEditField.Value = Group.blue.c.hr_sf;
                    app.EDASFEditField.Value = Group.blue.c.eda_sf;
                    app.TEMPSFEditField.Value = Group.blue.c.temp_sf;                   
                end
            elseif(strcmp(selected_group,'Green'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatebpmEditField.Value = Group.green.p.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.green.p.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.green.p.temp;
                    app.HRSFEditField.Value = Group.green.p.hr_sf;
                    app.EDASFEditField.Value = Group.green.p.eda_sf;
                    app.TEMPSFEditField.Value = Group.green.p.temp_sf;                   
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatebpmEditField.Value = Group.green.c.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.green.c.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.green.c.temp;
                    app.HRSFEditField.Value = Group.green.c.hr_sf;
                    app.EDASFEditField.Value = Group.green.c.eda_sf;
                    app.TEMPSFEditField.Value = Group.green.c.temp_sf;                 
                end            
            elseif(strcmp(selected_group,'Orange'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatebpmEditField.Value = Group.orange.p.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.orange.p.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.orange.p.temp;
                    app.HRSFEditField.Value = Group.orange.p.hr_sf;
                    app.EDASFEditField.Value = Group.orange.p.eda_sf;
                    app.TEMPSFEditField.Value = Group.orange.p.temp_sf;                 
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatebpmEditField.Value = Group.orange.c.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.orange.c.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.orange.c.temp;
                    app.HRSFEditField.Value = Group.ornge.c.hr_sf;
                    app.EDASFEditField.Value = Group.orange.c.eda_sf;
                    app.TEMPSFEditField.Value = Group.orange.c.temp_sf;                    
                end                
            elseif(strcmp(selected_group,'Red'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatebpmEditField.Value = Group.red.p.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.red.p.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.red.p.temp;
                    app.HRSFEditField.Value = Group.red.p.hr_sf;
                    app.EDASFEditField.Value = Group.red.p.eda_sf;
                    app.TEMPSFEditField.Value = Group.red.p.temp_sf;                   
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatebpmEditField.Value = Group.red.c.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.red.c.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.red.c.temp;
                    app.HRSFEditField.Value = Group.red.c.hr_sf;
                    app.EDASFEditField.Value = Group.red.c.eda_sf;
                    app.TEMPSFEditField.Value = Group.red.c.temp_sf;                   
                end             
            elseif(strcmp(selected_group,'Yellow'))
                if(strcmp(selected_user,'Participant'))
                    app.HeartRatebpmEditField.Value = Group.yellow.p.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.yellow.p.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.yellow.p.temp;
                    app.HRSFEditField.Value = Group.yellow.p.hr_sf;
                    app.EDASFEditField.Value = Group.yellow.p.eda_sf;
                    app.TEMPSFEditField.Value = Group.yellow.p.temp_sf;                 
                elseif(strcmp(selected_user,'Care-giver'))
                    app.HeartRatebpmEditField.Value = Group.yellow.c.hr;
                    app.ElectrodermalActivitychangeusEditField.Value = Group.yellow.c.eda;
                    app.TemperaturechangedegreeEditField.Value = Group.yellow.c.temp;
                    app.HRSFEditField.Value = Group.yellow.c.hr_sf;
                    app.EDASFEditField.Value = Group.yellow.c.eda_sf;
                    app.TEMPSFEditField.Value = Group.yellow.c.temp_sf;                    
                end              
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Group Threshold default values = Blue,Participant
            populate_group_threshold(app,'Blue','Participant');
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            % When the select files button is pressed we choose a
            % folder and we extract the data from the .CSV data files to
            % put them into the variables
            
            path = uigetdir;
            if isequal(path,0)
                disp('User selected Cancel');
                return;
            else
                disp(['User selected folder: ', path]);
                listing = dir(path);
                row_offset = 1;
                col_offset = 0;
                for i = 1:size(listing)
                    % We go through each files and we read the CSV into
                    % a table that we save in the appropriate file.
                    file_name = listing(i).name;
                    full_path = strcat(path,'\',file_name);
                    if(contains(file_name, 'p_bvp'))
                        disp(['File : ',file_name,' is bvp p']);
                        app.BVP_p =readtable(full_path);
                    elseif(contains(file_name, 'p_hr'))
                        disp(['File : ',file_name,' is HR p']);
                        app.HR_p =readtable(full_path);
                    elseif(contains(file_name, 'p_temp'))
                        disp(['File : ',file_name,' is TEMP p']);
                        app.TEMP_p =readtable(full_path);
                    elseif(contains(file_name, 'p_eda'))
                        disp(['File : ',file_name,' is EDA p']);
                        app.EDA_p =readtable(full_path);
                    elseif(contains(file_name, 'c_bvp'))
                        disp(['File : ',file_name,' is bvp c']);
                        app.BVP_c =readtable(full_path);
                    elseif(contains(file_name, 'c_hr'))
                        disp(['File : ',file_name,' is HR c']);
                        app.HR_c =readtable(full_path);
                    elseif(contains(file_name, 'c_temp'))
                        disp(['File : ',file_name,' is TEMP c']);
                        app.TEMP_c =readtable(full_path);
                    elseif(contains(file_name, 'c_eda'))
                        disp(['File : ',file_name,' is EDA c']);
                        app.EDA_c =readtable(full_path);
                    end
                    
                    app.readyLamp.Color = [0,1,0];
                    app.is_ready = 1;
                end
            end
        end

        % Button pushed function: FindtimepointsButton
        function FindtimepointsButtonPushed(app, event)
            % When the find time points button is pushed we load 
            % all the necessary variables before calling the find events function
            app.start_time = app.SystemStartTimeEditField.Value;
            if(app.is_ready && app.start_time > 0)
                clc;
                display('Program Launched');
                display('Loading variables...');
                app.number_markers = app.NumberofMarkersEditField.Value;
                app.group = app.SelectGroupDropDown.Value;
                temp_data = load('Group.mat');
                
                % Here we load the thresholds for the group we are trying to do
                if(strcmp(app.group,'Blue'))
                    app.thresholds = temp_data.Group.blue;
                elseif(strcmp(app.group,'Green'))
                    app.thresholds = temp_data.Group.green;
                elseif(strcmp(app.group,'Orange'))
                    app.thresholds = temp_data.Group.orange;
                elseif(strcmp(app.group,'Red'))
                    app.thresholds = temp_data.Group.red;
                elseif(strcmp(app.group,'Yellow'))
                    app.thresholds = temp_data.Group.yellow;
                end
                
                display('Calculating signal quality for participants...')
                [bvp_filt_p,bvp_score_p,bvp_sqi_p,eda_filt_p,eda_score_p,eda_sqi_p,temp_filt_p,temp_score_p,temp_sqi_p] = get_sqi(app,"p");
                display('Saving signal quality for participants...');
                save_sqi(app,"p",bvp_score_p,eda_score_p,temp_score_p);
                display('Calculating signal quality for care-giver...')
                [bvp_filt_c,bvp_score_c,bvp_sqi_c,eda_filt_c,eda_score_c,eda_sqi_c,temp_filt_c,temp_score_c,temp_sqi_c] = get_sqi(app,"c");
                display('Saving signal quality for care-giver...');
                save_sqi(app,"c",bvp_score_c,eda_score_c,temp_score_c);
                
                display('Overwrite raw data with filtered signal for EDA and TEMP...');
                % Convert cell to 'table'
                temp_filt_p = cellstr(num2str(temp_filt_p));
                temp_filt_c = cellstr(num2str(temp_filt_c));
                eda_filt_p = cellstr(num2str(eda_filt_p));
                eda_filt_c = cellstr(num2str(eda_filt_c)); 
                
                app.TEMP_p(:,2) = temp_filt_p;
                app.TEMP_c(:,2) = temp_filt_c;
                app.EDA_p(:,2) = eda_filt_p;
                app.EDA_c(:,2) = eda_filt_c;                

                assignin('base','BVP_p',app.BVP_p);
                assignin('base','HR_p',app.HR_p);      
                assignin('base','TEMP_p',app.TEMP_p);                
                assignin('base','EDA_p',app.EDA_p);        
                assignin('base','BVP_c',app.BVP_c);                
                assignin('base','HR_c',app.HR_c);                
                assignin('base','TEMP_c',app.TEMP_c);                
                assignin('base','EDA_c',app.EDA_c);      
                
                display('Finding events for participants...');
                app.SQI_TEMP_p = temp_sqi_p;
                app.SQI_EDA_p = eda_sqi_p;
                app.SQI_TEMP_c = temp_sqi_c;
                app.SQI_EDA_c = eda_sqi_c;
                
                start_time = app.SystemStartTimeEditField.Value;
                [events_p,hr_time_p,hr_averaged_p,temp_time_p,temp_averaged_p,temp_averaged_sqi_p,eda_time_p,eda_averaged_p,eda_averaged_sqi_p] = find_events(app,"p");
                display('Finding events for care-giver...');
                [events_c,hr_time_c,hr_averaged_c,temp_time_c,temp_averaged_c,temp_averaged_sqi_c,eda_time_c,eda_averaged_c,eda_averaged_sqi_c] = find_events(app,"c");
                display('Save events for participants...');
                save_events(app,events_p,"p");
                save_events_type(app,events_p,"p");
                display('Save events for care-giver...');
                save_events(app,events_c,"c");
                save_events_type(app,events_c,"c");
                
                if(app.ParticipantCheckBox.Value == 1)
                    display('Plotting Participant...');
                    plot_data_markers('Participant',start_time,eda_time_p,eda_averaged_p,eda_averaged_sqi_p,...
                    hr_time_p,hr_averaged_p,temp_time_p,temp_averaged_p,temp_averaged_sqi_p,events_p,app.event_type_p,'r');
                end
                
                if(app.CaregiverCheckBox.Value == 1)
                    display('Plotting Care-giver...');
                    plot_data_markers('Caregiver',start_time,eda_time_c,eda_averaged_c,eda_averaged_sqi_c,...
                    hr_time_c,hr_averaged_c,temp_time_c,temp_averaged_c,temp_averaged_sqi_c,events_c,app.event_type_c,'b');
                end
                display('Program Terminated!');
                
                
                % Here we are resetting the variables for the next iteration
                app.EDA_p = [];
                app.TEMP_p = [];
                app.HR_p = [];
                app.EDA_c = [];
                app.TEMP_c = [];
                app.HR_c = [];
                app.readyLamp.Color = [1,0,0];
                app.is_ready = 0;
            else
                display('Select a folder first and make sure start time > 0');
            end
            
        end

        % Button pushed function: UpdateThresholdButton
        function UpdateThresholdButtonPushed(app, event)
            % Collecting the threshold from the input fields
            HR_bpm = app.HeartRatebpmEditField.Value;
            EDA_change = app.ElectrodermalActivitychangeusEditField.Value;
            TEMP_change = app.TemperaturechangedegreeEditField.Value;
            HR_SF = app.HRSFEditField.Value;
            EDA_SF = app.EDASFEditField.Value;
            TEMP_SF = app.TEMPSFEditField.Value;
            selected_group = app.SelectGroupDropDownThreshold.Value;
            selected_user = app.SelectUserDropDown.Value;
            
            % Loading the Group structure
            Group = load('Group.mat');
            Group = Group.Group;
            
            % Changing the right field in the Group structure
            if(strcmp(selected_group,'Blue'))
                if(strcmp(selected_user,'Participant'))
                    Group.blue.p.eda = EDA_change;
                    Group.blue.p.hr = HR_bpm;
                    Group.blue.p.temp = TEMP_change;
                    Group.blue.p.hr_sf = HR_SF;
                    Group.blue.p.eda_sf = EDA_SF;
                    Group.blue.p.temp_sf = TEMP_SF;
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.blue.c.eda = EDA_change;
                    Group.blue.c.hr = HR_bpm;
                    Group.blue.c.temp = TEMP_change; 
                    Group.blue.c.hr_sf = HR_SF;
                    Group.blue.c.eda_sf = EDA_SF;
                    Group.blue.c.temp_sf = TEMP_SF;                    
                end
            elseif(strcmp(selected_group,'Green'))
                if(strcmp(selected_user,'Participant'))
                    Group.green.p.eda = EDA_change;
                    Group.green.p.hr = HR_bpm;
                    Group.green.p.temp = TEMP_change;
                    Group.green.p.hr_sf = HR_SF;
                    Group.green.p.eda_sf = EDA_SF;
                    Group.green.p.temp_sf = TEMP_SF;                    
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.green.c.eda = EDA_change;
                    Group.green.c.hr = HR_bpm;
                    Group.green.c.temp = TEMP_change;
                    Group.green.c.hr_sf = HR_SF;
                    Group.green.c.eda_sf = EDA_SF;
                    Group.green.c.temp_sf = TEMP_SF;                    
                end            
            elseif(strcmp(selected_group,'Orange'))
                if(strcmp(selected_user,'Participant'))
                    Group.orange.p.eda = EDA_change;
                    Group.orange.p.hr = HR_bpm;
                    Group.orange.p.temp = TEMP_change;
                    Group.orange.p.hr_sf = HR_SF;
                    Group.orange.p.eda_sf = EDA_SF;
                    Group.orange.p.temp_sf = TEMP_SF;                    
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.orange.c.eda = EDA_change;
                    Group.orange.c.hr = HR_bpm;
                    Group.orange.c.temp = TEMP_change; 
                    Group.orange.c.hr_sf = HR_SF;
                    Group.orange.c.eda_sf = EDA_SF;
                    Group.orange.c.temp_sf = TEMP_SF;                    
                end                
            elseif(strcmp(selected_group,'Red'))
                if(strcmp(selected_user,'Participant'))
                    Group.red.p.eda = EDA_change;
                    Group.red.p.hr = HR_bpm;
                    Group.red.p.temp = TEMP_change;
                    Group.red.p.hr_sf = HR_SF;
                    Group.red.p.eda_sf = EDA_SF;
                    Group.red.p.temp_sf = TEMP_SF;                    
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.red.c.eda = EDA_change;
                    Group.red.c.hr = HR_bpm;
                    Group.red.c.temp = TEMP_change;
                    Group.red.c.hr_sf = HR_SF;
                    Group.red.c.eda_sf = EDA_SF;
                    Group.red.c.temp_sf = TEMP_SF;                    
                end             
            elseif(strcmp(selected_group,'Yellow'))
                if(strcmp(selected_user,'Participant'))
                    Group.yellow.p.eda = EDA_change;
                    Group.yellow.p.hr = HR_bpm;
                    Group.yellow.p.temp = TEMP_change;
                    Group.yellow.p.hr_sf = HR_SF;
                    Group.yellow.p.eda_sf = EDA_SF;
                    Group.yellow.p.temp_sf = TEMP_SF;                    
                elseif(strcmp(selected_user,'Care-giver'))
                    Group.yellow.c.eda = EDA_change;
                    Group.yellow.c.hr = HR_bpm;
                    Group.yellow.c.temp = TEMP_change;  
                    Group.yellow.c.hr_sf = HR_SF;
                    Group.yellow.c.eda_sf = EDA_SF;
                    Group.yellow.c.temp_sf = TEMP_SF;                    
                end              
            end
            
            % Saving the Group data
            save('Group.mat','Group');
            display('Updated the Group threshold!');
        end

        % Value changed function: SelectUserDropDown
        function SelectUserDropDownValueChanged(app, event)
            sel_user = app.SelectUserDropDown.Value;
            sel_group = app.SelectGroupDropDownThreshold.Value;
            populate_group_threshold(app,sel_group,sel_user);
        end

        % Value changed function: SelectGroupDropDownThreshold
        function SelectGroupDropDownThresholdValueChanged(app, event)
            sel_user = app.SelectUserDropDown.Value;
            sel_group = app.SelectGroupDropDownThreshold.Value;
            populate_group_threshold(app,sel_group,sel_user);
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            path = uigetdir;
            if(path ~= 0)
               app.saving_directory = path;
               disp(['updated saving directory to : ',path]);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventFinderUIFigure = uifigure;
            app.EventFinderUIFigure.Name = 'Event Finder';
            app.FileMenu = uimenu(app.EventFinderUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.EventFinderUIFigure);
            app.EventFinderTab = uitab(app.TabGroup);
            app.EventFinderTab.Title = 'Event Finder';
            app.SelectFilesButton = uibutton(app.EventFinderTab, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.FindtimepointsButton = uibutton(app.EventFinderTab, 'push');
            app.FindtimepointsButton.ButtonPushedFcn = createCallbackFcn(app, @FindtimepointsButtonPushed, true);
            app.FindtimepointsButton.Text = 'Find time points';
            app.readyLampLabel = uilabel(app.EventFinderTab);
            app.readyLampLabel.Text = 'ready';
            app.readyLamp = uilamp(app.EventFinderTab);
            app.MarkerFileNameEditFieldLabel = uilabel(app.EventFinderTab);
            app.MarkerFileNameEditFieldLabel.Text = 'Marker File Name';
            app.MarkerFileNameEditField = uieditfield(app.EventFinderTab, 'text');
            app.SystemStartTimeEditFieldLabel = uilabel(app.EventFinderTab);
            app.SystemStartTimeEditFieldLabel.Text = 'System Start Time';
            app.SystemStartTimeEditField = uieditfield(app.EventFinderTab, 'numeric');
            app.SystemStartTimeEditField.Limits = [0 Inf];
            app.NumberofMarkersEditFieldLabel = uilabel(app.EventFinderTab);
            app.NumberofMarkersEditFieldLabel.Text = 'Number of Markers';
            app.NumberofMarkersEditField = uieditfield(app.EventFinderTab, 'numeric');
            app.NumberofMarkersEditField.Limits = [1 Inf];
            app.NumberofMarkersEditField.Value = 1;
            app.SelectGroupDropDownLabel = uilabel(app.EventFinderTab);
            app.SelectGroupDropDownLabel.Text = 'Select Group';
            app.SelectGroupDropDown = uidropdown(app.EventFinderTab);
            app.SelectGroupDropDown.Items = {'Blue', 'Green', 'Orange', 'Red', 'Yellow'};
            app.SelectGroupDropDown.Value = 'Blue';
            app.ParticipantCheckBox = uicheckbox(app.EventFinderTab);
            app.ParticipantCheckBox.Text = 'Participant';
            app.CaregiverCheckBox = uicheckbox(app.EventFinderTab);
            app.CaregiverCheckBox.Text = 'Caregiver';
            app.PlotGraphforLabel = uilabel(app.EventFinderTab);
            app.PlotGraphforLabel.Text = 'Plot Graph for';
            app.ShowMarkersforLabel = uilabel(app.EventFinderTab);
            app.ShowMarkersforLabel.Text = 'Show Markers for';
            app.EDACheckBox = uicheckbox(app.EventFinderTab);
            app.EDACheckBox.Text = 'EDA';
            app.HRCheckBox = uicheckbox(app.EventFinderTab);
            app.HRCheckBox.Text = 'HR';
            app.TempCheckBox = uicheckbox(app.EventFinderTab);
            app.TempCheckBox.Text = 'Temp';
            app.LoadDataLabel = uilabel(app.EventFinderTab);
            app.LoadDataLabel.Text = 'Load Data';
            app.SelectDirectoryButton = uibutton(app.EventFinderTab, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.SavingDirectoryLabel = uilabel(app.EventFinderTab);
            app.SavingDirectoryLabel.Text = 'Saving Directory';
            app.GroupThresholdTab = uitab(app.TabGroup);
            app.GroupThresholdTab.Title = 'Group Threshold';
            app.SelectGroupDropDown_2Label = uilabel(app.GroupThresholdTab);
            app.SelectGroupDropDown_2Label.Text = 'Select Group';
            app.SelectGroupDropDownThreshold = uidropdown(app.GroupThresholdTab);
            app.SelectGroupDropDownThreshold.Items = {'Blue', 'Green', 'Orange', 'Red', 'Yellow'};
            app.SelectGroupDropDownThreshold.ValueChangedFcn = createCallbackFcn(app, @SelectGroupDropDownThresholdValueChanged, true);
            app.SelectGroupDropDownThreshold.Value = 'Blue';
            app.UpdateThresholdButton = uibutton(app.GroupThresholdTab, 'push');
            app.UpdateThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateThresholdButtonPushed, true);
            app.UpdateThresholdButton.Text = 'Update Threshold';
            app.HeartRatebpmEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.HeartRatebpmEditFieldLabel.Text = 'Heart Rate (bpm)';
            app.HeartRatebpmEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.HeartRatebpmEditField.Limits = [0 Inf];
            app.HeartRatebpmEditField.Value = 10;
            app.ElectrodermalActivitychangeusEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.ElectrodermalActivitychangeusEditFieldLabel.Text = 'Electrodermal Activity change (us)';
            app.ElectrodermalActivitychangeusEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.ElectrodermalActivitychangeusEditField.Limits = [0 Inf];
            app.ElectrodermalActivitychangeusEditField.Value = 0.1;
            app.TemperaturechangedegreeEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.TemperaturechangedegreeEditFieldLabel.Text = 'Temperature change (degree)';
            app.TemperaturechangedegreeEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.TemperaturechangedegreeEditField.Limits = [0 Inf];
            app.TemperaturechangedegreeEditField.Value = 0.1;
            app.SelectUserDropDownLabel = uilabel(app.GroupThresholdTab);
            app.SelectUserDropDownLabel.Text = 'Select User';
            app.SelectUserDropDown = uidropdown(app.GroupThresholdTab);
            app.SelectUserDropDown.Items = {'Participant', 'Care-giver'};
            app.SelectUserDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectUserDropDownValueChanged, true);
            app.SelectUserDropDown.Value = 'Participant';
            app.HRSFEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.HRSFEditFieldLabel.Text = 'HR SF';
            app.HRSFEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.HRSFEditField.Limits = [0 Inf];
            app.EDASFEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.EDASFEditFieldLabel.Text = 'EDA SF';
            app.EDASFEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.EDASFEditField.Limits = [0 Inf];
            app.TEMPSFEditFieldLabel = uilabel(app.GroupThresholdTab);
            app.TEMPSFEditFieldLabel.Text = 'TEMP SF';
            app.TEMPSFEditField = uieditfield(app.GroupThresholdTab, 'numeric');
            app.TEMPSFEditField.Limits = [0 Inf];
        end
    end

    methods (Access = public)

        % Construct app
        function app = CSVtoTimePts

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EventFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventFinderUIFigure)
        end
    end
end